classdef SDMapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AISADUIFigure                   matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LOADPARAMETERSButton            matlab.ui.control.Button
        EXPORTDATAButton                matlab.ui.control.Button
        CLEARButton                     matlab.ui.control.Button
        IMSEPManrique2024Label          matlab.ui.control.Label
        GENERATEREPORTButton            matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        PowerSpectralDensityGraphPanel  matlab.ui.container.Panel
        ImprovedFOMDesignVariablesLabel  matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        RNNInferredDesignVariablesLabel  matlab.ui.control.Label
        DesignVariablesPanel            matlab.ui.container.Panel
        RuninferencetoperformarchitectureselectionLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SelectedArchitectureclicktoopenPanel  matlab.ui.container.Panel
        SpecificationsPanel             matlab.ui.container.Panel
        ResultingSpecificationsPanel    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TextAreaFOMOpt                  matlab.ui.control.TextArea
        TextAreaFOMVal                  matlab.ui.control.TextArea
        TextAreaFOMInv                  matlab.ui.control.TextArea
        TextAreaPowerANN                matlab.ui.control.TextArea
        TextAreaBWANN                   matlab.ui.control.TextArea
        TextAreaSNRANN                  matlab.ui.control.TextArea
        InverseRNNLabel                 matlab.ui.control.Label
        OPTIMIZEButton                  matlab.ui.control.Button
        VALIDATEButton                  matlab.ui.control.Button
        TextArea_Powerwo                matlab.ui.control.TextArea
        TextArea_BWwo                   matlab.ui.control.TextArea
        TextArea_SNRwo                  matlab.ui.control.TextArea
        TextArea_Powerw                 matlab.ui.control.TextArea
        TextArea_BWw                    matlab.ui.control.TextArea
        TextArea_SNRw                   matlab.ui.control.TextArea
        OptimizedLabel                  matlab.ui.control.Label
        ValidatedLabel                  matlab.ui.control.Label
        InputSpecificationsPanel        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        FOMdBTextArea                   matlab.ui.control.TextArea
        FOMdBTextAreaLabel              matlab.ui.control.Label
        PowermWEditField                matlab.ui.control.NumericEditField
        PowermWEditFieldLabel           matlab.ui.control.Label
        BWMHzEditField                  matlab.ui.control.NumericEditField
        BWMHzEditFieldLabel             matlab.ui.control.Label
        SNDRdBEditField                 matlab.ui.control.NumericEditField
        SNDRdBEditFieldLabel            matlab.ui.control.Label
        RUNINFERENCEButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AISADLabel                      matlab.ui.control.Label
    end

    
    properties (Access = public)
        arch = [] % architecture
        column_names = [] %dvars names
        aboutFigure = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fullPath = mfilename('fullpath');
            [path, name, ext] = fileparts(fullPath);
            cd(path);
            folders = genpath('..');
            addpath(folders);
        end

        % Value changed function: SNDRdBEditField
        function SNDRdBEditFieldValueChanged(app, event)
            value = app.SNDRdBEditField.Value;
            app.SNDRdBEditField.Limits = [50,150];
        end

        % Value changed function: BWMHzEditField
        function BWMHzEditFieldValueChanged(app, event)
            value = app.BWMHzEditField.Value;
            app.BWMHzEditField.Limits = [0.01,100];
        end

        % Value changed function: PowermWEditField
        function PowermWEditFieldValueChanged(app, event)
            value = app.PowermWEditField.Value;
            app.PowermWEditField.Limits = [0.01,50];
        end

        % Button pushed function: RUNINFERENCEButton
        function RUNINFERENCEButtonPushed(app, event)

            app.RUNINFERENCEButton.BackgroundColor = [0.87 0.60 0.60];

            % Inputs
            sndr = app.SNDRdBEditField.Value;
            assignin('base',"sndr",sndr)
            bw = 1e6*app.BWMHzEditField.Value;
            assignin('base',"bw",bw)
            save('bw.mat','bw')
            power = app.PowermWEditField.Value / 1e3;
            assignin('base',"power",power)

            fom = sndr + 10*log10(bw/power);
            input_specs = [fom,sndr,bw,power];
            inspecs_table = array2table(input_specs,'VariableNames',{'FOM','SNDR','BW','Power'});
            writetable(inspecs_table,'inspecs.csv')

            app.FOMdBTextArea.Enable = "on";
            app.FOMdBTextArea.Value = string(fom);

            % Perform predictions
            pyarch = py.predictions.classifier_prediction(sndr,bw,power);
            py.predictions.rnn_prediction(sndr,bw,power,pyarch);
            dvars = readtable('dvars.csv');
            save('dvars.mat',"dvars")
            app.arch = string(pyarch);
            architecture = app.arch;
            save('arch.mat','architecture')
            %dvars = table(dfdvars);
            assignin('base',"dvars",dvars)

            % Selected architecture
            app.RuninferencetoperformarchitectureselectionLabel.Enable = "on";
            app.RuninferencetoperformarchitectureselectionLabel.Text = app.arch;
            app.RuninferencetoperformarchitectureselectionLabel.FontColor = 'k';
            impath = strcat(app.arch,'.png');
            img = imread(impath);
            app.Image.Enable = "on";
            app.Image.ImageSource = img;
            
            % Predicted design vars:
            app.column_names = dvars.Properties.VariableNames;
            i = 1;
            dvars2disp = dvars;
            for variable = app.column_names
                if contains(variable,'gm')
                    app.column_names(i) = strcat(variable,' (mA/V)');
                    dvars2disp(1,i) = 1000.*dvars(1,i);
                elseif contains(variable,'Io')
                    app.column_names(i) = strcat(variable,' (mA)');
                    dvars2disp(1,i) = 1000.*dvars(1,i);
                elseif contains(variable,'Vn')
                    app.column_names(i) = strcat(variable,' (V/(Hz^1/2))');
                end
                i = i+1;
            end
            app.RNNInferredDesignVariablesLabel.Enable = "on";
            app.UITable.Enable = "on";
            app.UITable.ColumnName = app.column_names;
            app.UITable2.ColumnName = app.column_names; 
            app.UITable.Data = dvars2disp;

            % Enable running simulations
            %app.RunsimulationsButton.Enable = "on";
            
            % Enable verify inferred design
            app.VALIDATEButton.Enable = "on";
            %app.EstimateButton.Enable = "on";

            app.InverseRNNLabel.Enable = "on";
            app.ValidatedLabel.Enable = "on";

            architecture = app.arch;
            py.predictions.inverse_rnn_prediction(architecture);
            pred_specs_table = readtable('pred_specs.csv');
            
            app.TextAreaSNRANN.Enable = "on";
            pred_sndr = pred_specs_table.SNDR;
            app.TextAreaSNRANN.Value = string(pred_sndr);
            if pred_sndr < sndr
                app.TextAreaSNRANN.BackgroundColor = [0.6 0 0];
                app.TextAreaSNRANN.FontColor = 'w';
            else
                app.TextAreaSNRANN.BackgroundColor = [0 0.6 0];
                app.TextAreaSNRANN.FontColor = 'w';
            end

            app.TextAreaBWANN.Enable = "on";
            %bw = pred_specs_table.Bw;
            bw = bw/1e6;
            app.TextAreaBWANN.Value = string(bw);
            app.TextAreaPowerANN.Enable = "on";
            pred_power = pred_specs_table.Power;
            pred_power = pred_power*1000;
            app.TextAreaPowerANN.Value = string(pred_power);
            if pred_power/1000 > power % pred_power en mW
                app.TextAreaPowerANN.BackgroundColor = [0.6 0 0];
                app.TextAreaPowerANN.FontColor = 'w';
            else
                app.TextAreaPowerANN.BackgroundColor = [0 0.6 0];
                app.TextAreaPowerANN.FontColor = 'w';
            end

            pred_fom = pred_sndr + 10*log10(bw*1000000/(pred_power/1000));
            app.TextAreaFOMInv.Enable = "on";
            app.TextAreaFOMInv.Value = string(pred_fom);
            if pred_fom < fom
                app.TextAreaFOMInv.BackgroundColor = [0.6 0 0];
                app.TextAreaFOMInv.FontColor = 'w';
            else
                app.TextAreaFOMInv.BackgroundColor = [0 0.6 0];
                app.TextAreaFOMInv.FontColor = 'w';
            end
            
            
            %app.EstimateButton.BackgroundColor = [0.60 0.87 0.60];

            app.RUNINFERENCEButton.BackgroundColor = [0.60 0.87 0.60];

        end

        % Callback function
        function RunsimulationsButtonPushed(app, event)

            % app.RunsimulationsButton.BackgroundColor = [0.87 0.60 0.60];
            % 
            % app.ImprovedFOMDesignVariablesLabel.Enable = "on";
            % 
            % 
            % %Run Random Variations script
            % run('randomvariations.m');
            % 
            % %Run simulations
            % sel_arch = app.arch;
            % sim_file = strcat('sim',sel_arch,'.m');
            % %gp_file = strcat(sel_arch,'_GP.mat');
            % %load(gp_file);
            % run(sim_file);
            % table_sim = readtable('total_sims.csv');
            % ann_row = table_sim(1,:);
            % opt_idx = find(table_sim.FOM == max(table_sim.FOM));
            % opt_row = table_sim(opt_idx,:);
            % 
            % sndr_ann = ann_row.SNDR;
            % sndr_opt = opt_row.SNDR;
            % bw_ann = ann_row.Bw;
            % bw_ann_mhz = bw_ann/1e6;
            % bw_opt = opt_row.Bw;
            % bw_opt_mhz = bw_opt/1e6;
            % power_ann = table_sim.Power;
            % power_ann_mw = power_ann*1000;
            % power_opt = opt_row.Power;
            % power_opt_mw = power_opt*1000;
            % 
            % app.TextArea_SNRwo.Enable = "on";
            % app.TextArea_SNRwo.Value = string(sndr_ann);
            % app.TextArea_SNRw.Enable = "on";
            % app.TextArea_SNRw.Value = string(sndr_opt);
            % app.TextArea_BWwo.Enable = "on";
            % app.TextArea_BWwo.Value = string(bw_ann_mhz);
            % app.TextArea_BWw.Enable = "on";
            % app.TextArea_BWw.Value = string(bw_opt_mhz);
            % app.TextArea_Powerwo.Enable = "on";
            % app.TextArea_Powerwo.Value = string(power_ann_mw);
            % app.TextArea_Powerw.Enable = "on";
            % app.TextArea_Powerw.Value = string(power_opt_mw);
            % 
            % dvars_names = app.column_names;
            % dvars_opt = opt_row(:,dvars_names);
            % app.UITable2.Data = dvars_opt;
            % 
            % app.OptimizedLabel.Enable = "on";
            % app.ValidatedLabel.Enable = "on";
            % app.UITable2.Enable = "on";
            % app.GENERATEREPORTButton.Enable = "on";
            % app.EXPORTDATAButton.Enable = "on";
            % app.Image2.Enable = "on";
            % app.Image2.ImageSource = 'figPSD.png';
            % 
            % 
            % app.RunsimulationsButton.BackgroundColor = [0.60 0.87 0.60];
        end

        % Callback function
        function ComputeButtonPushed(app, event)
            app.UITable2.Enable = "on";
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.SNDRdBEditField.Value = '';
            app.BWMHzEditField.Value = '';
            app.PowermWEditField.Value = '';

            app.ValidatedLabel.Enable = "off";
            app.OptimizedLabel.Enable = "off";

            app.TextArea_SNRwo.Value = '';
            app.TextArea_SNRwo.Enable = "off";
            app.TextArea_BWwo.Value = '';
            app.TextArea_BWwo.Enable = "off";
            app.TextArea_Powerwo.Value = '';
            app.TextArea_Powerwo.Enable = "off";
            app.TextArea_SNRw.Value = '';
            app.TextArea_SNRw.Enable = "off";
            app.TextArea_BWw.Value = '';
            app.TextArea_BWw.Enable = "off";
            app.TextArea_Powerw.Value = '';
            app.TextArea_Powerw.Enable = "off";

            app.TextAreaSNRANN.Value = '';
            app.TextAreaSNRANN.Enable = "off";
            app.TextAreaBWANN.Value = '';
            app.TextAreaBWANN.Enable = "off";
            app.TextAreaPowerANN.Value = '';
            app.TextAreaPowerANN.Enable = "off";

            app.TextAreaPowerANN.FontColor = 'k';
            app.TextAreaPowerANN.BackgroundColor = 'w';
            app.TextAreaSNRANN.FontColor = 'k';
            app.TextAreaSNRANN.BackgroundColor = 'w';
            app.TextArea_Powerwo.FontColor = 'k';
            app.TextArea_Powerwo.BackgroundColor = 'w';
            app.TextArea_SNRwo.FontColor = 'k';
            app.TextArea_SNRwo.BackgroundColor = 'w';
            app.TextArea_Powerw.FontColor = 'k';
            app.TextArea_Powerw.BackgroundColor = 'w';
            app.TextArea_SNRw.FontColor = 'k';
            app.TextArea_SNRw.BackgroundColor = 'w';

            app.FOMdBTextArea.Value = '';
            app.TextAreaFOMInv.Enable = 'off';
            app.TextAreaFOMInv.Value = '';
            app.TextAreaFOMInv.BackgroundColor = 'w';
            app.TextAreaFOMInv.FontColor = 'k';
            app.TextAreaFOMVal.Enable = 'off';
            app.TextAreaFOMVal.Value = '';
            app.TextAreaFOMVal.BackgroundColor = 'w';
            app.TextAreaFOMVal.FontColor = 'k';
            app.TextAreaFOMOpt.Enable = 'off';
            app.TextAreaFOMOpt.Value = '';
            app.TextAreaFOMOpt.BackgroundColor = 'w';
            app.TextAreaFOMOpt.FontColor = 'k';
           

            %app.RunsimulationsButton.BackgroundColor = [0.80 0.80 0.80];
            %app.RunsimulationsButton.Enable = "off";
            app.VALIDATEButton.BackgroundColor = [0.8 0.8 0.8];
            app.VALIDATEButton.Enable = "off";
            app.VALIDATEButton.Text = 'VALIDATE';
            app.OPTIMIZEButton.BackgroundColor = [0.8 0.8 0.8];
            app.OPTIMIZEButton.Enable = "off";
            app.OPTIMIZEButton.Text = 'OPTIMIZE';
            
            %app.EstimateButton.Enable = "off";
            %app.EstimateButton.BackgroundColor = [0.8 0.8 0.8];

            app.InverseRNNLabel.Enable = "off";

            app.RUNINFERENCEButton.BackgroundColor = [0.80 0.80 0.80];

            app.Image.Enable = "off";
            app.Image.ImageSource = '';
            app.Image2.Enable = "off";
            app.Image2.ImageSource = '';
            app.RuninferencetoperformarchitectureselectionLabel.Enable = "off";
            
            app.UITable.ColumnName = {'DV1','DV2','DV3','DV4'};
            app.UITable.Enable = "off";
            app.UITable.Data = {'','','',''};

            app.UITable2.ColumnName = {'DV1','DV2','DV3','DV4'};
            app.UITable2.Enable = "off";
            app.UITable2.Data = {'','','',''};

            app.GENERATEREPORTButton.Enable = "off";
            app.EXPORTDATAButton.Enable = "off";
            
            app.LOADPARAMETERSButton.Enable = "off";

            close all
            
           
        end

        % Button pushed function: GENERATEREPORTButton
        function GENERATEREPORTButtonPushed(app, event)
            generate_report;
        end

        % Button pushed function: EXPORTDATAButton
        function EXPORTDATAButtonPushed(app, event)
            table_totsims = readtable('total_sims.csv');
            [file,location] = uiputfile('simsdata.csv','Save data');
            A = [file ~= 0, location ~= 0];

            if all(A)
                path = strcat(location,file);
                writetable(table_totsims,path)
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            if app.Image.Enable == "on"
                model = app.arch;
                switch model
                    case '2ndGmCSDM'
                        GmC2ndCTSDMParam
                    case '2ndSCSDM_MultiBit'
                        SecondOrderMultibitSC
                    case '2ndSCSDM_SingleBit'
                        SecondOrderSingleBitSC
                    case '3or21SCSDM'
                        ThirdOrderCascadeSingleBitSC
                    case '4th211SCSDM'
                        umts211_real_PM1
                end
            end
        end

        % Button pushed function: VALIDATEButton
        function VALIDATEButtonPushed(app, event)
            app.VALIDATEButton.BackgroundColor = [0.87 0.60 0.60];
            app.VALIDATEButton.Text = 'VALIDATING...';

            %Run simulation
            sel_arch = app.arch;
            sim_file = strcat('verify',sel_arch,'.m');
            run(sim_file);
            table_sim = readtable('verification_results.csv');
            
            sndr = table_sim.SNDR;
            bw = table_sim.Bw;
            bw_mhz = bw/1e6;
            power = table_sim.Power;
            power_mw = power*1000;

            fom = sndr + 10*log10(bw/power);
            
            req_sndr = app.SNDRdBEditField.Value;
            req_power = app.PowermWEditField.Value;
            req_fom = app.FOMdBTextArea.Value;

            app.TextArea_SNRwo.Enable = "on";
            app.TextArea_SNRwo.Value = string(sndr);
            
            if sndr < req_sndr
                app.TextArea_SNRwo.BackgroundColor = [0.6 0 0];
                app.TextArea_SNRwo.FontColor = 'w';
            else
                app.TextArea_SNRwo.BackgroundColor = [0 0.6 0];
                app.TextArea_SNRwo.FontColor = 'w';
            end
            

            app.TextArea_BWwo.Enable = "on";
            app.TextArea_BWwo.Value = string(bw_mhz);

            app.TextArea_Powerwo.Enable = "on";
            app.TextArea_Powerwo.Value = string(power_mw);

            if power_mw > req_power
                app.TextArea_Powerwo.FontColor = 'w';
                app.TextArea_Powerwo.BackgroundColor = [0.6 0 0];
            else
                app.TextArea_Powerwo.FontColor = 'w';
                app.TextArea_Powerwo.BackgroundColor = [0 0.6 0];
            end

            app.TextAreaFOMVal.Enable = "on";
            app.TextAreaFOMVal.Value = string(fom);

            if fom < str2double(req_fom{1,1})
                app.TextAreaFOMVal.FontColor = 'w';
                app.TextAreaFOMVal.BackgroundColor = [0.6 0 0];
            else
                app.TextAreaFOMVal.FontColor = 'w';
                app.TextAreaFOMVal.BackgroundColor = [0 0.6 0];
            end

            app.ValidatedLabel.Enable = "on";
            app.Image2.Enable = "on";
            app.Image2.ImageSource = 'figPSD_verification.png';


            app.VALIDATEButton.BackgroundColor = [0.60 0.87 0.60];
            app.VALIDATEButton.Text = 'VALIDATE';

            app.OPTIMIZEButton.Enable = "on";
            app.OptimizedLabel.Enable = "on";
        end

        % Button pushed function: OPTIMIZEButton
        function OPTIMIZEButtonPushed(app, event)
            app.OPTIMIZEButton.BackgroundColor = [0.87 0.60 0.60];
            app.OPTIMIZEButton.Text = 'OPTIMIZING...';

            app.ImprovedFOMDesignVariablesLabel.Enable = "on";
            

            %Run Random Variations script
            run('randomvariations.m');

            %Run simulations
            sel_arch = app.arch;
            sim_file = strcat('optimize',sel_arch,'.m');
            run(sim_file);
            total_sim = readtable('total_sims.csv');
            ann_row = total_sim(1,:);
            opt_idx = find(total_sim.FOM == max(total_sim.FOM));
            opt_row = total_sim(opt_idx,:);
            
            fom_ann = ann_row.FOM;
            fom_opt = opt_row.FOM;


            sndr_opt = opt_row.SNDR;

            bw_opt = opt_row.Bw;
            bw_opt_mhz = bw_opt/1e6;

            power_opt = opt_row.Power;
            power_opt_mw = power_opt*1000;

            req_sndr = app.SNDRdBEditField.Value;
            req_power = app.PowermWEditField.Value;
            req_fom = app.FOMdBTextArea.Value;
            
            %rnn_sndr = str2double(app.TextArea_SNRwo.Value);
            %rnn_power = str2double(app.TextArea_Powerwo.Value);

            app.TextArea_SNRw.Enable = "on";
            app.TextArea_SNRw.Value = string(sndr_opt);
            if sndr_opt > req_sndr
                app.TextArea_SNRw.FontColor = 'w';
                app.TextArea_SNRw.BackgroundColor = [0 0.6 0];
            else
                app.TextArea_SNRw.FontColor = 'w';
                app.TextArea_SNRw.BackgroundColor = [0.6 0 0];
            end

            
            app.TextArea_BWw.Enable = "on";
            app.TextArea_BWw.Value = string(bw_opt_mhz);

            app.TextArea_Powerw.Enable = "on";
            app.TextArea_Powerw.Value = string(power_opt_mw);
            if power_opt_mw < req_power
                app.TextArea_Powerw.FontColor = 'w';
                app.TextArea_Powerw.BackgroundColor = [0 0.6 0];
            else
                app.TextArea_Powerw.FontColor = 'w';
                app.TextArea_Powerw.BackgroundColor = [0.6 0 0];
            end

            app.TextAreaFOMOpt.Enable = "on";
            app.TextAreaFOMOpt.Value = string(fom_opt);
            if fom_opt < str2double(req_fom{1,1})
                app.TextAreaFOMOpt.FontColor = 'w';
                app.TextAreaFOMOpt.BackgroundColor = [0.6 0 0];
            else
                app.TextAreaFOMOpt.FontColor = 'w';
                app.TextAreaFOMOpt.BackgroundColor = [0 0.6 0];
            end
            
            dvars_opt = opt_row(:,5:end);

            i = 1;
            dvarsopt2disp = dvars_opt;
            for variable = app.column_names
                if contains(variable,'gm')
                    dvarsopt2disp(1,i) = 1000.*dvars_opt(1,i);
                elseif contains(variable,'Io')
                    dvarsopt2disp(1,i) = 1000.*dvars_opt(1,i);
                end
                i = i+1;
            end

            app.UITable2.Data = dvars_opt;

            app.OptimizedLabel.Enable = "on";

            app.UITable2.Enable = "on";
            app.GENERATEREPORTButton.Enable = "on";
            app.EXPORTDATAButton.Enable = "on";
            app.Image2.Enable = "on";
            app.LOADPARAMETERSButton.Enable = "on";

            if fom_opt > fom_ann    
                app.Image2.ImageSource = 'figPSD_optimization.png';
            else
                app.Image2.ImageSource = 'figPSD_verification.png';
            end

            app.OPTIMIZEButton.Text = 'OPTIMIZE';
            app.OPTIMIZEButton.BackgroundColor = [0.60 0.87 0.60];
        end

        % Button pushed function: LOADPARAMETERSButton
        function LOADPARAMETERSButtonPushed(app, event)
            evalin('base','load("optimal_parameters.mat")')
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)
        end

        % Callback function
        function EstimateButtonPushed(app, event)
        %    architecture = app.arch;
        %    py.predictions.inverse_rnn_prediction(architecture);
        %    pred_specs_table = readtable('pred_specs.csv');
        %    
        %    app.TextAreaSNRANN.Enable = "on";
        %    sndr = pred_specs_table.SNDR;
        %    app.TextAreaSNRANN.Value = string(sndr);
        %    app.TextAreaBWANN.Enable = "on";
        %    bw = pred_specs_table.Bw;
        %    bw = bw/1e6;
        %    app.TextAreaBWANN.Value = string(bw);
        %    app.TextAreaPowerANN.Enable = "on";
        %    power = pred_specs_table.Power;
        %    power = power*1000;
        %    app.TextAreaPowerANN.Value = string(power);

        %    app.EstimateButton.BackgroundColor = [0.60 0.87 0.60];
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            app.SNDRdBEditField.Value = '';
            app.BWMHzEditField.Value = '';
            app.PowermWEditField.Value = '';

            app.ValidatedLabel.Enable = "off";
            app.OptimizedLabel.Enable = "off";

            app.TextArea_SNRwo.Value = '';
            app.TextArea_SNRwo.Enable = "off";
            app.TextArea_BWwo.Value = '';
            app.TextArea_BWwo.Enable = "off";
            app.TextArea_Powerwo.Value = '';
            app.TextArea_Powerwo.Enable = "off";
            app.TextArea_SNRw.Value = '';
            app.TextArea_SNRw.Enable = "off";
            app.TextArea_BWw.Value = '';
            app.TextArea_BWw.Enable = "off";
            app.TextArea_Powerw.Value = '';
            app.TextArea_Powerw.Enable = "off";

            app.TextAreaSNRANN.Value = '';
            app.TextAreaSNRANN.Enable = "off";
            app.TextAreaBWANN.Value = '';
            app.TextAreaBWANN.Enable = "off";
            app.TextAreaPowerANN.Value = '';
            app.TextAreaPowerANN.Enable = "off";

            app.TextAreaPowerANN.FontColor = 'k';
            app.TextAreaPowerANN.BackgroundColor = 'w';
            app.TextAreaSNRANN.FontColor = 'k';
            app.TextAreaSNRANN.BackgroundColor = 'w';
            app.TextArea_Powerwo.FontColor = 'k';
            app.TextArea_Powerwo.BackgroundColor = 'w';
            app.TextArea_SNRwo.FontColor = 'k';
            app.TextArea_SNRwo.BackgroundColor = 'w';
            app.TextArea_Powerw.FontColor = 'k';
            app.TextArea_Powerw.BackgroundColor = 'w';
            app.TextArea_SNRw.FontColor = 'k';
            app.TextArea_SNRw.BackgroundColor = 'w';

            app.FOMdBTextArea.Value = '';
            app.TextAreaFOMInv.Enable = 'off';
            app.TextAreaFOMInv.Value = '';
            app.TextAreaFOMInv.BackgroundColor = 'w';
            app.TextAreaFOMInv.FontColor = 'k';
            app.TextAreaFOMVal.Enable = 'off';
            app.TextAreaFOMVal.Value = '';
            app.TextAreaFOMVal.BackgroundColor = 'w';
            app.TextAreaFOMVal.FontColor = 'k';
            app.TextAreaFOMOpt.Enable = 'off';
            app.TextAreaFOMOpt.Value = '';
            app.TextAreaFOMOpt.BackgroundColor = 'w';
            app.TextAreaFOMOpt.FontColor = 'k';

            %app.RunsimulationsButton.BackgroundColor = [0.80 0.80 0.80];
            %app.RunsimulationsButton.Enable = "off";
            app.VALIDATEButton.BackgroundColor = [0.8 0.8 0.8];
            app.VALIDATEButton.Enable = "off";
            app.VALIDATEButton.Text = 'VALIDATE';
            app.OPTIMIZEButton.BackgroundColor = [0.8 0.8 0.8];
            app.OPTIMIZEButton.Enable = "off";
            app.OPTIMIZEButton.Text = 'OPTIMIZE';
            
            %app.EstimateButton.Enable = "off";
            %app.EstimateButton.BackgroundColor = [0.8 0.8 0.8];

            app.InverseRNNLabel.Enable = "off";

            app.RUNINFERENCEButton.BackgroundColor = [0.80 0.80 0.80];

            app.Image.Enable = "off";
            app.Image.ImageSource = '';
            app.Image2.Enable = "off";
            app.Image2.ImageSource = '';
            app.RuninferencetoperformarchitectureselectionLabel.Enable = "off";
            
            app.UITable.ColumnName = {'DV1','DV2','DV3','DV4'};
            app.UITable.Enable = "off";
            app.UITable.Data = {'','','',''};

            app.UITable2.ColumnName = {'DV1','DV2','DV3','DV4'};
            app.UITable2.Enable = "off";
            app.UITable2.Data = {'','','',''};

            app.GENERATEREPORTButton.Enable = "off";
            app.EXPORTDATAButton.Enable = "off";
            
            app.LOADPARAMETERSButton.Enable = "off";

            close all
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            % Create a new figure for the explanatory window
            aboutFig = uifigure('Name', 'About AISAD', 'Icon', 'logo_bw.png', 'Position', [100 100 400 300]);
    
            % Add a text area to display the explanation
            uitextarea(aboutFig, 'Value', {
                ['AISAD - AI-assisted Synthesis toolbox for Analog/Digital interfaces - allows ' ...
                'the user to obtain an ADC design given a set of specifications (SNDR, signal Band-Width and' ...
                'Power Consumption).']
                ''
                ['USAGE: To use AISAD, type your input specifications in the corresponding boxes. Then, after clicking' ...
                ' the RUN INFERENCE button, AISAD will automatically select the best SDM architecture for those specifications among the following:']
                '1. Second Order Single Bit SC-SDM'
                '2. Second Order Multi Bit SC-SDM'
                '3. Third Order 2-1 Cascade SC-SDM'
                '4. Fourth Order 2-1-1 Cascade SC-SDM'
                'Also, an Artificial Neural Network (ANN) will infer design variables that should meet the required specifications.'
                ['To check the results, an "Inverse" ANN will infer specifications from the previously obtained design variables.' ...
                'This is performed automatically when pressing the RUN INFERENCE button.']
                ['In addition, user can VALIDATE the results performing a behavioral simulation using SIMSIDES,' ...
                'and obtain "real" specifications.']
                'It is also posible to OPTIMIZE the results and obtain design variables that lead to higher FOM value.'
                ['User can finally generate a report containing all the information, save the data and load the optimized parameters' ...
                ' into the MATLAB Workspace using the bottom buttons. If parameters are loaded (LOAD PARAMETERS button pushed), by clicking' ...
                ' the Architecture image, the corresponding Simulink model will open and it will be ready to run simulations, for example' ...
                ' to use SIMSIDES.']
                ''
                'For further information, read the AISAD paper or check the repository.'
                }, 'Position', [20 20 360 260], 'Editable', 'off');

            app.aboutFigure = aboutFig;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AISADUIFigure = uifigure('Visible', 'off');
            app.AISADUIFigure.Name = 'AISAD';
            app.AISADUIFigure.Icon = fullfile(pathToMLAPP, 'logo_bw.png');
            app.Menu = uimenu(app.AISADUIFigure);
            app.Menu.Text = 'Menu';
            app.ClearMenu = uimenu(app.Menu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.AboutMenu = uimenu(app.Menu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.GridLayout = uigridlayout(app.AISADUIFigure);
            app.AISADLabel = uilabel(app.GridLayout);
            app.AISADLabel.Tooltip = {''};
            app.AISADLabel.Text = 'AISAD';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'DV1'; 'DV2'; 'DV3'; 'DV4'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.InputSpecificationsPanel = uipanel(app.GridLayout);
            app.InputSpecificationsPanel.Title = 'Input Specifications';
            app.GridLayout2 = uigridlayout(app.InputSpecificationsPanel);
            app.RUNINFERENCEButton = uibutton(app.GridLayout2, 'push');
            app.RUNINFERENCEButton.ButtonPushedFcn = createCallbackFcn(app, @RUNINFERENCEButtonPushed, true);
            app.RUNINFERENCEButton.Text = 'RUN INFERENCE';
            app.SNDRdBEditFieldLabel = uilabel(app.GridLayout2);
            app.SNDRdBEditFieldLabel.Text = 'SNDR (dB)';
            app.SNDRdBEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SNDRdBEditField.AllowEmpty = 'on';
            app.SNDRdBEditField.ValueChangedFcn = createCallbackFcn(app, @SNDRdBEditFieldValueChanged, true);
            app.BWMHzEditFieldLabel = uilabel(app.GridLayout2);
            app.BWMHzEditFieldLabel.Text = 'BW (MHz)';
            app.BWMHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BWMHzEditField.AllowEmpty = 'on';
            app.BWMHzEditField.ValueChangedFcn = createCallbackFcn(app, @BWMHzEditFieldValueChanged, true);
            app.PowermWEditFieldLabel = uilabel(app.GridLayout2);
            app.PowermWEditFieldLabel.Text = 'Power (mW)';
            app.PowermWEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PowermWEditField.AllowEmpty = 'on';
            app.PowermWEditField.ValueChangedFcn = createCallbackFcn(app, @PowermWEditFieldValueChanged, true);
            app.FOMdBTextAreaLabel = uilabel(app.GridLayout2);
            app.FOMdBTextAreaLabel.Text = 'FOM (dB)';
            app.FOMdBTextArea = uitextarea(app.GridLayout2);
            app.FOMdBTextArea.Editable = 'off';
            app.FOMdBTextArea.Enable = 'off';
            app.ResultingSpecificationsPanel = uipanel(app.GridLayout);
            app.ResultingSpecificationsPanel.Title = 'Resulting Specifications';
            app.GridLayout3 = uigridlayout(app.ResultingSpecificationsPanel);
            app.ValidatedLabel = uilabel(app.GridLayout3);
            app.ValidatedLabel.Enable = 'off';
            app.ValidatedLabel.Text = 'Validated';
            app.OptimizedLabel = uilabel(app.GridLayout3);
            app.OptimizedLabel.Enable = 'off';
            app.OptimizedLabel.Text = 'Optimized';
            app.TextArea_SNRw = uitextarea(app.GridLayout3);
            app.TextArea_SNRw.Editable = 'off';
            app.TextArea_SNRw.Enable = 'off';
            app.TextArea_BWw = uitextarea(app.GridLayout3);
            app.TextArea_BWw.Editable = 'off';
            app.TextArea_BWw.Enable = 'off';
            app.TextArea_Powerw = uitextarea(app.GridLayout3);
            app.TextArea_Powerw.Editable = 'off';
            app.TextArea_Powerw.Enable = 'off';
            app.TextArea_SNRwo = uitextarea(app.GridLayout3);
            app.TextArea_SNRwo.Editable = 'off';
            app.TextArea_SNRwo.Enable = 'off';
            app.TextArea_BWwo = uitextarea(app.GridLayout3);
            app.TextArea_BWwo.Editable = 'off';
            app.TextArea_BWwo.Enable = 'off';
            app.TextArea_Powerwo = uitextarea(app.GridLayout3);
            app.TextArea_Powerwo.Editable = 'off';
            app.TextArea_Powerwo.Enable = 'off';
            app.VALIDATEButton = uibutton(app.GridLayout3, 'push');
            app.VALIDATEButton.ButtonPushedFcn = createCallbackFcn(app, @VALIDATEButtonPushed, true);
            app.VALIDATEButton.Enable = 'off';
            app.VALIDATEButton.Text = 'VALIDATE';
            app.OPTIMIZEButton = uibutton(app.GridLayout3, 'push');
            app.OPTIMIZEButton.ButtonPushedFcn = createCallbackFcn(app, @OPTIMIZEButtonPushed, true);
            app.OPTIMIZEButton.Enable = 'off';
            app.OPTIMIZEButton.Text = 'OPTIMIZE';
            app.InverseRNNLabel = uilabel(app.GridLayout3);
            app.InverseRNNLabel.Enable = 'off';
            app.InverseRNNLabel.Text = 'Inverse RNN';
            app.TextAreaSNRANN = uitextarea(app.GridLayout3);
            app.TextAreaSNRANN.Editable = 'off';
            app.TextAreaSNRANN.Enable = 'off';
            app.TextAreaBWANN = uitextarea(app.GridLayout3);
            app.TextAreaBWANN.Editable = 'off';
            app.TextAreaBWANN.Enable = 'off';
            app.TextAreaPowerANN = uitextarea(app.GridLayout3);
            app.TextAreaPowerANN.Editable = 'off';
            app.TextAreaPowerANN.Enable = 'off';
            app.TextAreaFOMInv = uitextarea(app.GridLayout3);
            app.TextAreaFOMInv.Editable = 'off';
            app.TextAreaFOMInv.Enable = 'off';
            app.TextAreaFOMVal = uitextarea(app.GridLayout3);
            app.TextAreaFOMVal.Editable = 'off';
            app.TextAreaFOMVal.Enable = 'off';
            app.TextAreaFOMOpt = uitextarea(app.GridLayout3);
            app.TextAreaFOMOpt.Editable = 'off';
            app.TextAreaFOMOpt.Enable = 'off';
            app.SpecificationsPanel = uipanel(app.GridLayout);
            app.SpecificationsPanel.Title = 'Specifications';
            app.SelectedArchitectureclicktoopenPanel = uipanel(app.GridLayout);
            app.SelectedArchitectureclicktoopenPanel.Title = 'Selected Architecture (click to open)';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Enable = 'off';
            app.RuninferencetoperformarchitectureselectionLabel = uilabel(app.GridLayout);
            app.RuninferencetoperformarchitectureselectionLabel.Text = 'Run inference to perform architecture selection';
            app.DesignVariablesPanel = uipanel(app.GridLayout);
            app.DesignVariablesPanel.Title = 'Design Variables';
            app.RNNInferredDesignVariablesLabel = uilabel(app.GridLayout);
            app.RNNInferredDesignVariablesLabel.Text = 'RNN Inferred Design Variables:';
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'DV1'; 'DV2'; 'DV3'; 'DV4'};
            app.UITable2.RowName = {};
            app.UITable2.Enable = 'off';
            app.ImprovedFOMDesignVariablesLabel = uilabel(app.GridLayout);
            app.ImprovedFOMDesignVariablesLabel.Text = 'Improved FOM Design Variables:';
            app.PowerSpectralDensityGraphPanel = uipanel(app.GridLayout);
            app.PowerSpectralDensityGraphPanel.Title = 'Power Spectral Density Graph';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.Enable = 'off';
            app.GENERATEREPORTButton = uibutton(app.GridLayout, 'push');
            app.GENERATEREPORTButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEREPORTButtonPushed, true);
            app.GENERATEREPORTButton.Enable = 'off';
            app.GENERATEREPORTButton.Text = 'GENERATE REPORT';
            app.IMSEPManrique2024Label = uilabel(app.GridLayout);
            app.IMSEPManrique2024Label.Text = 'IMSE - P. Manrique 2024';
            app.CLEARButton = uibutton(app.GridLayout, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.EXPORTDATAButton = uibutton(app.GridLayout, 'push');
            app.EXPORTDATAButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTDATAButtonPushed, true);
            app.EXPORTDATAButton.Enable = 'off';
            app.EXPORTDATAButton.Text = 'EXPORT DATA';
            app.LOADPARAMETERSButton = uibutton(app.GridLayout, 'push');
            app.LOADPARAMETERSButton.ButtonPushedFcn = createCallbackFcn(app, @LOADPARAMETERSButtonPushed, true);
            app.LOADPARAMETERSButton.Enable = 'off';
            app.LOADPARAMETERSButton.Text = 'LOAD PARAMETERS';
            % Show the figure after all components are created
            app.AISADUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDMapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AISADUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AISADUIFigure)
        end
    end
end